classdef TONE_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        LOADButton     matlab.ui.control.Button
        PLAYButton     matlab.ui.control.Button
        PAUSEButton    matlab.ui.control.Button
        STOPButton     matlab.ui.control.Button
        TONEKnobLabel  matlab.ui.control.Label
        TONEKnob       matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Data;
        Nummat;
        Num;
        SamplesPerFrame;
        fileInfo;
        fileReader;
        deviceWriter;
        Hd;
        stopFlag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data = load('nummat.mat');
            app.Nummat = app.Data.nummat;
            % Параметры и системные объекты чтения файла
            app.SamplesPerFrame = 100;
            app.fileInfo = audioinfo('funky-guitar.wav');
            app.fileReader = dsp.AudioFileReader('funky-guitar.wav','PlayCount',inf);
            app.fileReader.SamplesPerFrame = app.SamplesPerFrame;
            % Системный объект воспроизведения
            app.deviceWriter = audioDeviceWriter('SampleRate', app.fileInfo.SampleRate);
            % Системный объект КИХ-фильтра с изменяемыми коэффициентами
            app.Hd = dsp.FIRFilter('Structure','Direct form symmetric','NumeratorSource','Input port');
            app.Num = app.Nummat(str2double(app.TONEKnob.Value),:);
            [h,f] = freqz(app.Num,1,[],app.fileInfo.SampleRate);
            plot(app.UIAxes,f,10*log10(abs(h)),'LineWidth',2);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            [file,path] = uigetfile({'*.mp3';'*.wav';'*.*'});
            addpath(path);
            app.fileInfo = audioinfo(file);
            app.fileReader = dsp.AudioFileReader(file,'PlayCount',inf);
            app.fileReader.SamplesPerFrame = app.SamplesPerFrame;
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.stopFlag = false;
            while ~isDone(app.fileReader)
                audioData  = app.fileReader(); 
                audioData  = app.Hd(audioData,app.Num); 
                app.deviceWriter(audioData);
                if app.stopFlag == true
                    break;
                end
                drawnow();
            end
        end

        % Value changed function: TONEKnob
        function TONEKnobValueChanged(app, event)
            app.Num = app.Nummat(str2double(app.TONEKnob.Value),:);
            [h,f] = freqz(app.Num,1,[],app.fileInfo.SampleRate);
            plot(app.UIAxes,f,10*log10(abs(h)),'LineWidth',2);
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.stopFlag = true;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stopFlag = true;
            reset(app.fileReader);
            reset(app.deviceWriter);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 20000];
            app.UIAxes.YLim = [-60 10];
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.PAUSEButton = uibutton(app.UIFigure, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TONEKnobLabel = uilabel(app.UIFigure);
            app.TONEKnobLabel.Text = 'TONE';
            app.TONEKnob = uiknob(app.UIFigure, 'discrete');
            app.TONEKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.TONEKnob.ValueChangedFcn = createCallbackFcn(app, @TONEKnobValueChanged, true);
            app.TONEKnob.Value = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TONE_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end